classdef SignalProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        ImportFileButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [file,path] = uigetfile('*.wav','Select a .wav file'); % Prompts user to select a file for input and stores the filename and path as variables
            fullPath = append(path, file); % Converts the filename and path to a single string to be read by the audioread function
            % add file validation routine
            y = audioread(fullPath); % Stores amplitude data in an array
            plot(app.UIAxes, y); % Plots the data on the axes to display the waveform of the file
            app.UIAxes.Title.String = file;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '[ Import a file ]')
            xlabel(app.UIAxes, 'Time /s')
            ylabel(app.UIAxes, 'Amplitude /dB')
            zlabel(app.UIAxes, 'Z')
            app.ImportFileButton = uibutton(app.GridLayout, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end